classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Label_2               matlab.ui.control.Label
        JenisKendaraanyangterdeteksiolehprogramCNNLabel  matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        JenisKendaraanyangterdeteksiolehprogramnondeeplearningLabel  matlab.ui.control.Label
        IdentifikasiJenisKendaraanButton  matlab.ui.control.Button
        SistemPengenalanJenisKendaraanLabel  matlab.ui.control.Label
        CitraMasukanPanel     matlab.ui.container.Panel
        ImageAxes             matlab.ui.control.UIAxes
        PathInfoLabel         matlab.ui.control.Label
        PathcitraLabel        matlab.ui.control.Label
        PilihFileCitraButton  matlab.ui.control.Button
        CitramasukanLabel     matlab.ui.control.Label
    end

    
    properties (Access = public)
        Image
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PilihFileCitraButton
        function PilihFileCitraButtonPushed(app, event)
            % Memilih gambar
            [fileName, pathName] = uigetfile({'*.png; *.jpg; *.jpeg; *.bmp; *.tif;', 'All Image Files'});
            fullPath = fullfile(pathName, fileName);

            if fileName ~= 0
                % Menampilkan path file di label
                app.PathInfoLabel.Text = fullPath;
                
                app.Image = imread(fullPath);
                imshow(app.Image, 'Parent', app.ImageAxes);

                % Toggle Button
                app.IdentifikasiJenisKendaraanButton.Enable = "on";
            end
        end

        % Button pushed function: IdentifikasiJenisKendaraanButton
        function IdentifikasiJenisKendaraanButtonPushed(app, event)
            app.Label.Text = 'Memproses...';
            app.Label_2.Text = 'Memproses...';
            drawnow;
            app.Label.Text = string(knn(app.Image));
            app.Label_2.Text = string(cnn(app.Image));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CitramasukanLabel = uilabel(app.UIFigure);
            app.CitramasukanLabel.Text = 'Citra masukan:';
            app.PilihFileCitraButton = uibutton(app.UIFigure, 'push');
            app.PilihFileCitraButton.ButtonPushedFcn = createCallbackFcn(app, @PilihFileCitraButtonPushed, true);
            app.PilihFileCitraButton.Text = 'Pilih File Citra';
            app.PathcitraLabel = uilabel(app.UIFigure);
            app.PathcitraLabel.Text = 'Path citra:';
            app.PathInfoLabel = uilabel(app.UIFigure);
            app.PathInfoLabel.Text = '';
            app.CitraMasukanPanel = uipanel(app.UIFigure);
            app.CitraMasukanPanel.Title = 'Citra Masukan';
            app.ImageAxes = uiaxes(app.CitraMasukanPanel);
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.SistemPengenalanJenisKendaraanLabel = uilabel(app.UIFigure);
            app.SistemPengenalanJenisKendaraanLabel.Text = 'Sistem Pengenalan Jenis Kendaraan';
            app.IdentifikasiJenisKendaraanButton = uibutton(app.UIFigure, 'push');
            app.IdentifikasiJenisKendaraanButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifikasiJenisKendaraanButtonPushed, true);
            app.IdentifikasiJenisKendaraanButton.Enable = 'off';
            app.IdentifikasiJenisKendaraanButton.Text = 'Identifikasi Jenis Kendaraan';
            app.JenisKendaraanyangterdeteksiolehprogramnondeeplearningLabel = uilabel(app.UIFigure);
            app.JenisKendaraanyangterdeteksiolehprogramnondeeplearningLabel.Text = {'Jenis Kendaraan yang terdeteksi oleh'; 'program non deep learning:'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.JenisKendaraanyangterdeteksiolehprogramCNNLabel = uilabel(app.UIFigure);
            app.JenisKendaraanyangterdeteksiolehprogramCNNLabel.Text = {'Jenis Kendaraan yang terdeteksi oleh'; 'program CNN:'};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end